classdef Angleapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        UIAxes    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        callingapp
        Cube % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapphandle)
            a = -pi : pi/2 : pi;                                % Define Corners
            ph = pi/4;                                          % Define Angular Orientation (‘Phase’)
            x = [cos(a+ph); cos(a+ph)]*.5/cos(ph);
            y = [sin(a+ph); sin(a+ph)]*.5/sin(ph);
            z = [zeros(size(a)); ones(size(a))];
            
            app.Cube(1)=surf(app.UIAxes,x, y, z, 'FaceColor','cyan');                      % Plot Cube
            hold(app.UIAxes,"on");
            z = [-ones(size(a)); zeros(size(a))];
            app.Cube(2)=surf(app.UIAxes,x, y, z, 'FaceColor','cyan');
            z = [-ones(size(a)); ones(size(a))];
            app.Cube(3)=patch(app.UIAxes,x', y', z', 'cyan');                              % Make Cube Appear Solid
            axis(app.UIAxes,[ -1  1    -1  1    -1  1]*.5*2.33);
            hold(app.UIAxes,"off");
            app.callingapp = mainapphandle;
            app.callingapp.AnglesButton.Enable ="off";
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            for i=0:360
                rotate(app.Cube,[ 1 0 0],1);
                pause(0.001);
            end
            for i=0:360
                rotate(app.Cube,[0 1 0],1);
                pause(0.001);
            end
            for i=0:360
                rotate(app.Cube,[0 0 1],1);
                pause(0.001);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingapp.AnglesHandle = [];
            app.callingapp.AnglesButton.Enable ="on";
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Simulator')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Angleapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end