classdef Magnetometerapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        zEditField       matlab.ui.control.EditField
        zEditFieldLabel  matlab.ui.control.Label
        yEditField       matlab.ui.control.EditField
        yEditFieldLabel  matlab.ui.control.Label
        xEditField       matlab.ui.control.EditField
        xEditFieldLabel  matlab.ui.control.Label
        ReadButton       matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end


    properties (Access = private)
        callingapp % Description
        data = [];
    end
    methods (Access = public)

        function dispMagneto(app,Magnetos)
            app.xEditField.Value=num2str(Magnetos(1));
            app.yEditField.Value=num2str(Magnetos(2));
            app.zEditField.Value=num2str(Magnetos(3));
            app.data(end+1,:) = Magnetos;
            if size(app.data,1)>1
                plot(app.UIAxes,app.data);
            end
        end
    end
    methods (Access = private)

        function MagnetoCallback(app,~,~)
            array = app.callingapp.getframe(19);
            [frame,status] = Process(array,"in");
            if status
                app.callingapp.msgLabel.Text = reshape(dec2hex(frame,2)',1,[]);
                Magnetos(1)=typecast((uint8(frame( 4: 7))),'single');
                Magnetos(2)=typecast((uint8(frame( 8:11))),'single');
                Magnetos(3)=typecast((uint8(frame(12:15))),'single');
                app.dispMagneto(Magnetos);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapphandler)
            app.callingapp = mainapphandler;
            mainapphandler.MagnetometerButton.Enable = "off";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingapp.MagnetometerHandle=[];
            app.callingapp.MagnetometerButton.Enable = "on";
            delete(app)
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            command = [0x07,0x01,12];
            app.callingapp.send(command,@app.MagnetoCallback,19);
            %             command = Process(command,"out");
            %             configureCallback(app.callingapp.Serialdev,"terminator",@app.MagnetoCallback);
            %             write(app.callingapp.Serialdev,command,"uint8");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Magnetometer')
            zlabel(app.UIAxes, 'Z')
            app.ReadButton = uibutton(app.UIFigure, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'text');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'text');
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Magnetometerapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end