classdef Sunsensorsapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ReadButton       matlab.ui.control.Button
        EditField5       matlab.ui.control.EditField
        EditField5Label  matlab.ui.control.Label
        EditField4       matlab.ui.control.EditField
        EditField4Label  matlab.ui.control.Label
        EditField3       matlab.ui.control.EditField
        EditField3Label  matlab.ui.control.Label
        EditField2       matlab.ui.control.EditField
        EditField2Label  matlab.ui.control.Label
        EditField        matlab.ui.control.EditField
        EditFieldLabel   matlab.ui.control.Label
        UIAxes           matlab.ui.control.UIAxes
    end


    properties (Access = private)
        callingapp % Description
        data = [];
    end
    methods (Access = public)

        function dispSunsensors(app,ssdata)
            app.EditField.Value=num2str(ssdata(1));
            app.EditField2.Value=num2str(ssdata(2));
            app.EditField3.Value=num2str(ssdata(3));
            app.EditField4.Value=num2str(ssdata(4));
            app.EditField5.Value=num2str(ssdata(5));
            app.data(end+1,:) = ssdata;
            if size(app.data,1)>1
                plot(app.UIAxes,app.data);
            end

        end
    end
    methods (Access = private)

        function SunsensorCallback(app,~,~)
            array = app.callingapp.getframe(27);
            app.callingapp.msgLabel.Text = reshape(dec2hex(array,2)',1,[]);
            [frame,status] = Process(array,"in");
            if status
                ss =[];

                ss(1)=typecast((uint8(frame( 4: 7))),'single');
                ss(2)=typecast((uint8(frame( 8:11))),'single');
                ss(3)=typecast((uint8(frame(12:15))),'single');
                ss(4)=typecast((uint8(frame(16:19))),'single');
                ss(5)=typecast((uint8(frame(20:23))),'single');
                app.dispSunsensors(ss);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapphandler)
            app.callingapp = mainapphandler;
            app.callingapp.SunsensorButton.Enable = "off";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingapp.SunsensorButton.Enable = "on";
            app.callingapp.SunsensorsHandle=[];
            delete(app)
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            command = [0x07,0x01,14];
            app.callingapp.send(command,@app.SunsensorCallback,27);
            %             command = Process(command,"out");
            %             configureCallback(app.callingapp.Serialdev,"terminator",@app.SunsensorCallback);
            %             write(app.callingapp.Serialdev,command,"uint8");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sun Sensors Data')
            app.UIAxes.LineStyleOrder = {'-'};
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.EditField3Label = uilabel(app.UIFigure);
            app.EditField3Label.Text = 'Edit Field3';
            app.EditField3 = uieditfield(app.UIFigure, 'text');
            app.EditField4Label = uilabel(app.UIFigure);
            app.EditField4Label.Text = 'Edit Field4';
            app.EditField4 = uieditfield(app.UIFigure, 'text');
            app.EditField5Label = uilabel(app.UIFigure);
            app.EditField5Label.Text = 'Edit Field5';
            app.EditField5 = uieditfield(app.UIFigure, 'text');
            app.ReadButton = uibutton(app.UIFigure, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sunsensorsapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end