classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUIFigure             matlab.ui.Figure
        ConnectionPanel          matlab.ui.container.Panel
        SerialstateLabel         matlab.ui.control.Label
        StatusLabel              matlab.ui.control.Label
        ConnectionLabel          matlab.ui.control.Label
        ConnectButton            matlab.ui.control.Button
        UpdateSerialButton       matlab.ui.control.Button
        BaudRateEditField        matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel   matlab.ui.control.Label
        SerialPortDropDown       matlab.ui.control.DropDown
        SerialPortDropDownLabel  matlab.ui.control.Label
        AnglesPanel              matlab.ui.container.Panel
        GyroPanel                matlab.ui.container.Panel
        LogPanel                 matlab.ui.container.Panel
        MemoryPanel              matlab.ui.container.Panel
        MotorsPanel              matlab.ui.container.Panel
        SunSensorsPanel          matlab.ui.container.Panel
        MagnetoPanel             matlab.ui.container.Panel
        TempPanel                matlab.ui.container.Panel
        APPsPanel                matlab.ui.container.Panel
        MotorsButton             matlab.ui.control.Button
        Lamp                     matlab.ui.control.Lamp
        PingButton               matlab.ui.control.Button
        TelemetryButton          matlab.ui.control.Button
        TempButton               matlab.ui.control.Button
        AnglesButton             matlab.ui.control.Button
        LatchButton              matlab.ui.control.Button
        LogButton                matlab.ui.control.Button
        MemoryButton             matlab.ui.control.Button
        SunsensorButton          matlab.ui.control.Button
        MagnetometerButton       matlab.ui.control.Button
        GyroscopeButton          matlab.ui.control.Button
    end

    properties (Access = public)
        Serialdev % the serial port to which we connect
        GyroscopeHandle
        MagnetometerHandle
        SunsensorsHandle
        LogHandle
        MotorsHandle
        MemoryHandle
        AnglesHandle
        TemperatureHandle
        isLatched = 0
        timerx
    end

    methods (Access = public)

        function array = getframe(app,num)     % this function is used to check the call back system
            try
                array = read( app.Serialdev,num,"uint8");
                while array(end) ~= 0xc0
                    pause(0.01);
                    array(end+1)= read( app.Serialdev,1,"uint8"); %#ok<AGROW>
                end
            catch
                array = ones(num,"uint8");
            end
        end
        function send(app,command,callback,num)
            if isempty(app.Serialdev)
            else
                flush(app.Serialdev);
                command = Process(command,"out");
                configureCallback(app.Serialdev,"byte",num,callback);
                write(app.Serialdev,command,"uint8");
            end
        end
    end
    methods (Access = private)
        function PingCallback(app,~,~)
            array = getframe(app,7);
            [frame,status] = Process(array,"in");
            if status == 1 && frame(3) == 0x02
                app.Lamp.Color = 'g';
                app.Lamp.Visible = 'on';
            else
                app.Lamp.Color = 'r';
                app.Lamp.Visible = 'on';
            end
        end
        function LatchCallback(app,~,~)
            array = getframe(app,7);
            [~,status] = Process(array,"in");
            if status
                if app.isLatched
                    % got ack after start latch
                    app.LatchButton.Icon = 'Latch-open.png';
                else
                    app.LatchButton.Icon = 'Latch-close.png';
                end
            end
        end
        function TelmetryCallback(app,~,~)
            array = getframe(app,55);
            [frame,status] = Process(array,"in");
            if status
                Telmetry = zeros(12,"single");
                for i=7:4:51
                    Telmetry((i-3)/4) = typecast((uint8(frame( i-3: i))),'single');
                end
                if ~isempty(app.SunsensorsHandle)
                    app.SunsensorsHandle.dispSunsensors(Telmetry(8:12));
                end
                if ~isempty(app.GyroscopeHandle)
                    app.GyroscopeHandle.dispGyro(Telmetry(1:3));
                end
                if ~isempty(app.MagnetometerHandle)
                    app.MagnetometerHandle.dispMagneto(Telmetry(4:6));
                end
                if ~isempty(app.TemperatureHandle)
                    app.TemperatureHandle.dispTemp(Telmetry(7));
                end

            end
        end
        function TimerCallback(app,~,~)
            command = [0x07,0x01,15];
            app.send(command,@app.TelmetryCallback,55);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [~,y]=system('wmic path win32_pnpentity get name /format:table |find "COM"');
            str = strsplit(y,'\n');
            str = strtrim(str);
            app.SerialPortDropDown.Items = str(1:end-1);
            app.SerialstateLabel.FontColor = 'r';
            app.SerialstateLabel.Text = "Disconnected";
%             set(app.MainUIFigure,'Unit','normalized','Position',[0 0 1 1]);
        end

        % Button pushed function: UpdateSerialButton
        function UpdateSerialButtonPushed(app, event)
            [~,y]=system('wmic path win32_pnpentity get name /format:table |find "COM"');
            str = strsplit(y,'\n');
            str = strtrim(str);
            app.SerialPortDropDown.Items = str(1:end-1);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if isempty(app.Serialdev)
                serialname = convertStringsToChars(extractBetween( ...
                    app.SerialPortDropDown.Value,'(',')'));
                serialname = serialname{1};
                serialbaud = app.BaudRateEditField.Value;
                try
                    app.Serialdev = serialport(serialname,serialbaud);
                    configureCallback(app.Serialdev,"off");
                    command = [0x07,0x01,0x00];
                    command = Process(command,'out');
                    write(app.Serialdev,command,"uint8");
                    pause(0.1);
                    array = getframe(app,7);%read( app.Serialdev,7,"uint8");
                    ACK = uint8([0xC0 0x01 0x07 0x02 0xF5 0x0D 0xC0]);
                    if array == ACK
                        app.SerialstateLabel.FontColor = 'g';
                        app.SerialstateLabel.Text = "Connected";
                        app.APPsPanel.Visible = "on";
                        app.APPsPanel.Enable = "on" ;
                        app.ConnectionPanel.Enable = "off";
                        app.ConnectionPanel.Visible = "off";
                    else
                        app.SerialstateLabel.Text = "Could't Connect";
                    end
                catch
                    app.SerialstateLabel.Text = "Error : choose proper COM";
                end

            else
                app.Serialdev=[];
                app.SerialstateLabel.FontColor = 'r';
                app.SerialstateLabel.Text = "Disconnected";
                app.ConnectButton.Text = "Connect";
            end
        end

        % Button pushed function: PingButton
        function PingButtonPushed(app, event)
            command = [0x07,0x01,0x00];
            app.Lamp.Visible = 'off';
            app.send(command,@app.PingCallback,7);
            %             command = Process(command,"out");
            %             configureCallback(app.Serialdev,"terminator",@app.PingCallback);
            %             write(app.Serialdev,command,"uint8");
        end

        % Button pushed function: GyroscopeButton
        function GyroscopeButtonPushed(app, event)
            app.GyroscopeHandle = Gyroscopapp(app);
        end

        % Close request function: MainUIFigure
        function MainUIFigureCloseRequest(app, event)
            delete(app.GyroscopeHandle);
            delete(app.MagnetometerHandle);
            delete(app.SunsensorsHandle);
            delete(app.LogHandle);
            delete(app.MotorsHandle);
            delete(app.MemoryHandle);
            delete(app.AnglesHandle);
            delete(app.TemperatureHandle);
            delete(app);
        end

        % Button pushed function: MagnetometerButton
        function MagnetometerButtonPushed(app, event)
            app.MagnetometerHandle = Magnetometerapp(app);
        end

        % Button pushed function: SunsensorButton
        function SunsensorButtonPushed(app, event)
            app.SunsensorsHandle = Sunsensorsapp(app);
        end

        % Button pushed function: LogButton
        function LogButtonPushed(app, event)
            app.LogHandle = Logapp(app);
        end

        % Button pushed function: LatchButton
        function LatchButtonPushed(app, event)
            if app.isLatched
                command = [0x07,0x01,25];
                app.isLatched = 0;
            else
                command = [0x07,0x01,24];
                app.isLatched = 1;
            end
            app.send(command,@app.LatchCallback,7);
        end

        % Button pushed function: MotorsButton
        function MotorsButtonPushed(app, event)
            app.MotorsHandle = Motorsapp(app);
        end

        % Button pushed function: MemoryButton
        function MemoryButtonPushed(app, event)
            app.MemoryHandle = Memoryapp(app);
        end

        % Button pushed function: AnglesButton
        function AnglesButtonPushed(app, event)
            app.AnglesHandle = Angleapp(app);
        end

        % Button pushed function: TempButton
        function TempButtonPushed(app, event)
            app.TemperatureHandle = Temperatureapp(app);
        end

        % Button pushed function: TelemetryButton
        function TelemetryButtonPushed(app, event)
%             if ~isempty(app.AnglesHandle) && ~isempty(app.GyroscopeHandle)...
%                     && ~isempty(app.MagnetometerHandle) && ~isempty(app.SunsensorsHandle)...
%                     && ~isempty(app.LogHandle) && ~isempty(app.MemoryHandle)...
%                     && ~isempty(app.MotorsHandle) && ~isempty(app.TemperatureHandle)
%                 if app.isLatched == 0
%                     if isempty(app.timerx)
%                         app.timerx = timer('TimerFcn',@(~,~)app.TimerCallback,'Period',0.1);
%                         app.timerx.TasksToExecute = inf;
%                         app.timerx.ExecutionMode = 'fixedRate';
%                         start(app.timerx);
%                     else
%                         stop(app.timerx);
%                         app.timerx =[];
%                     end
%                 end
%             else
%                 if isempty(app.AnglesHandle)
                    app.AnglesHandle = Angleapp(app);
%                     waitfor(app.AnglesHandle.SimulatorUIFigure);
%                 end
%                 if isempty(app.GyroscopeHandle)
                    app.GyroscopeHandle = Gyroscopapp(app);
%                 end
%                 if isempty(app.MagnetometerHandle)
                    app.MagnetometerHandle = Magnetometerapp(app);
%                 end
%                 if isempty(app.SunsensorsHandle)
                    app.SunsensorsHandle = Sunsensorsapp(app);
%                 end
%                 if isempty(app.LogHandle)
                    app.LogHandle = Logapp(app);
%                 end
%                 if isempty(app.MemoryHandle)
                    app.MemoryHandle = Memoryapp(app);
%                 end
%                 if isempty(app.MotorsHandle)
                    app.MotorsHandle = Motorsapp(app);
%                 end

%                 if isempty(app.TemperatureHandle)
                    app.TemperatureHandle = Temperatureapp(app);
%                 end
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainUIFigure = uifigure('Visible', 'off');
            app.MainUIFigure.Name = 'Main';
            app.MainUIFigure.Icon = 'home.png';
            app.MainUIFigure.CloseRequestFcn = createCallbackFcn(app, @MainUIFigureCloseRequest, true);
            app.APPsPanel = uipanel(app.MainUIFigure);
            app.GyroscopeButton = uibutton(app.APPsPanel, 'push');
            app.GyroscopeButton.ButtonPushedFcn = createCallbackFcn(app, @GyroscopeButtonPushed, true);
            app.GyroscopeButton.Icon = 'Gyroscope.png';
            app.GyroscopeButton.Text = '';
            app.MagnetometerButton = uibutton(app.APPsPanel, 'push');
            app.MagnetometerButton.ButtonPushedFcn = createCallbackFcn(app, @MagnetometerButtonPushed, true);
            app.MagnetometerButton.Icon = 'Magneto.png';
            app.MagnetometerButton.Text = '';
            app.SunsensorButton = uibutton(app.APPsPanel, 'push');
            app.SunsensorButton.ButtonPushedFcn = createCallbackFcn(app, @SunsensorButtonPushed, true);
            app.SunsensorButton.Icon = 'SunSensors.png';
            app.SunsensorButton.Text = '';
            app.MemoryButton = uibutton(app.APPsPanel, 'push');
            app.MemoryButton.ButtonPushedFcn = createCallbackFcn(app, @MemoryButtonPushed, true);
            app.MemoryButton.Icon = 'Memory.png';
            app.MemoryButton.Text = '';
            app.LogButton = uibutton(app.APPsPanel, 'push');
            app.LogButton.ButtonPushedFcn = createCallbackFcn(app, @LogButtonPushed, true);
            app.LogButton.Icon = 'Log.png';
            app.LogButton.Text = '';
            app.LatchButton = uibutton(app.APPsPanel, 'push');
            app.LatchButton.ButtonPushedFcn = createCallbackFcn(app, @LatchButtonPushed, true);
            app.LatchButton.Icon = 'Latch-close.png';
            app.LatchButton.Text = '';
            app.AnglesButton = uibutton(app.APPsPanel, 'push');
            app.AnglesButton.ButtonPushedFcn = createCallbackFcn(app, @AnglesButtonPushed, true);
            app.AnglesButton.Icon = 'Simulator.png';
            app.AnglesButton.Text = '';
            app.TempButton = uibutton(app.APPsPanel, 'push');
            app.TempButton.ButtonPushedFcn = createCallbackFcn(app, @TempButtonPushed, true);
            app.TempButton.Icon = 'Temperature.png';
            app.TempButton.Text = '';
            app.TelemetryButton = uibutton(app.APPsPanel, 'push');
            app.TelemetryButton.ButtonPushedFcn = createCallbackFcn(app, @TelemetryButtonPushed, true);
            app.TelemetryButton.Icon = 'Telemetry.png';
            app.TelemetryButton.Text = '';
            app.PingButton = uibutton(app.APPsPanel, 'push');
            app.PingButton.ButtonPushedFcn = createCallbackFcn(app, @PingButtonPushed, true);
            app.PingButton.Icon = 'Ping.png';
            app.PingButton.Text = '';
            app.Lamp = uilamp(app.APPsPanel);
            app.Lamp.Visible = 'off';
            app.MotorsButton = uibutton(app.APPsPanel, 'push');
            app.MotorsButton.ButtonPushedFcn = createCallbackFcn(app, @MotorsButtonPushed, true);
            app.MotorsButton.Icon = 'Motors.png';
            app.MotorsButton.Text = '';
            app.TempPanel = uipanel(app.MainUIFigure);
            app.TempPanel.Enable = 'off';
            app.TempPanel.Visible = 'off';
            app.MagnetoPanel = uipanel(app.MainUIFigure);
            app.MagnetoPanel.Enable = 'off';
            app.MagnetoPanel.Visible = 'off';
            app.SunSensorsPanel = uipanel(app.MainUIFigure);
            app.SunSensorsPanel.Enable = 'off';
            app.SunSensorsPanel.Visible = 'off';
            app.MotorsPanel = uipanel(app.MainUIFigure);
            app.MotorsPanel.Enable = 'off';
            app.MotorsPanel.Visible = 'off';
            app.MemoryPanel = uipanel(app.MainUIFigure);
            app.MemoryPanel.Enable = 'off';
            app.MemoryPanel.Visible = 'off';
            app.LogPanel = uipanel(app.MainUIFigure);
            app.LogPanel.Enable = 'off';
            app.LogPanel.Visible = 'off';
            app.GyroPanel = uipanel(app.MainUIFigure);
            app.GyroPanel.Enable = 'off';
            app.GyroPanel.Visible = 'off';
            app.AnglesPanel = uipanel(app.MainUIFigure);
            app.AnglesPanel.Enable = 'off';
            app.AnglesPanel.Visible = 'off';
            app.ConnectionPanel = uipanel(app.MainUIFigure);
            app.SerialPortDropDownLabel = uilabel(app.ConnectionPanel);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.ConnectionPanel);
            app.SerialPortDropDown.Items = {};
            app.SerialPortDropDown.Value = {};
            app.BaudRateEditFieldLabel = uilabel(app.ConnectionPanel);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.ConnectionPanel, 'numeric');
            app.BaudRateEditField.ValueDisplayFormat = '%.0f';
            app.BaudRateEditField.Value = 115200;
            app.UpdateSerialButton = uibutton(app.ConnectionPanel, 'push');
            app.UpdateSerialButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSerialButtonPushed, true);
            app.UpdateSerialButton.Text = 'Update Serial';
            app.ConnectButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ConnectionLabel = uilabel(app.ConnectionPanel);
            app.ConnectionLabel.Text = 'Connection';
            app.StatusLabel = uilabel(app.ConnectionPanel);
            app.StatusLabel.Text = 'Status :';
            app.SerialstateLabel = uilabel(app.ConnectionPanel);
            app.SerialstateLabel.Text = '';
            % Show the figure after all components are created
            app.MainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUIFigure)
        end
    end
end